classdef Final_Project_Feet_Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        ExpertmodeButton               matlab.ui.control.StateButton
        UselastpathCheckBox            matlab.ui.control.CheckBox
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        RGBDimensionsButtonGroup       matlab.ui.container.ButtonGroup
        AllButton                      matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataProcessingTab              matlab.ui.container.Tab
        ContactDetectionPanel          matlab.ui.container.Panel
        ProcessButton                  matlab.ui.control.Button
        RGBDimensionsButtonGroup_2     matlab.ui.container.ButtonGroup
        BlueButton_2                   matlab.ui.control.RadioButton
        GreenButton_2                  matlab.ui.control.RadioButton
        RedButton_2                    matlab.ui.control.RadioButton
        UIAxes2                        matlab.ui.control.UIAxes
        BackgroundDetectionPanel       matlab.ui.container.Panel
        Detection_window_EditField     matlab.ui.control.NumericEditField
        DetectionWindowEditFieldLabel  matlab.ui.control.Label
        Iterations_EditField           matlab.ui.control.NumericEditField
        alliterationsLabel             matlab.ui.control.Label
        Neighbour_threshold_Slider     matlab.ui.control.Slider
        NeighbourthresholdLabel        matlab.ui.control.Label
        Refine_detection_Button        matlab.ui.control.Button
        Detect_foot_pixel_Button       matlab.ui.control.Button
        Threshold_Slider               matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        LandmarkIdentificationTab      matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Property % Description
        directory
        file
        app_settings
        working_directory
        version
        image_display_counter
    end

    methods (Access = private)       
        %% differentiates between image and background
        function image_cut_out = background_removal(app, image, color, threshold)
            %color white = 1
            %color black = 0
            SizeP = size(app.file.double);
            for i_row = 1 : SizeP(1) % rows
                for i_col = 1 : SizeP(2) % columns
                    if color
                        if image(i_row,i_col,1)>=(threshold/100) && image(i_row,i_col,2)>=(threshold/100) && image(i_row,i_col,3)>=(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    else
                        if image(i_row,i_col,1)<= 1-(threshold/100) && image(i_row,i_col,2)<= 1-(threshold/100) && image(i_row,i_col,3)<= 1-(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                            image_cut_out(i_row,i_col) = 0;
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    end
                end
            end
        end
        %% refines image/background seperation by checking neighbours
        function output_logical = Neighborhood_refinement(app, image_logical)
            Size_log = size(image_logical);
            output_logical = nan(Size_log);
            Raster = ones(app.Detection_window_EditField.Value); % Raster of detection
            Neighborhood = conv2(image_logical, Raster, 'same'); % determine the sum of the neighbouring pixels
            cut_off = (app.Neighbour_threshold_Slider.Value .* numel(Raster) ) ./ 100;

            for row = 1:size(image_logical,1)
                for col = 1:size(image_logical,2)
                    for dim = 1:size(image_logical,3)
                        if Neighborhood(row,col,dim) >= cut_off
                            output_logical(row,col,dim) = 1;
                        elseif Neighborhood(row,col,dim) < cut_off
                            output_logical(row,col,dim) = 0;
                        end
                    end
                end
            end

        end 

        %% function changes mouse cursor
        function hourglass_cursor(app,value)
        if value
            set(app.UIFigure,'Pointer', 'watch');
            drawnow;
        else
            set(app.UIFigure,'Pointer', 'arrow');
            drawnow;
        end       
        end

        %% safes images of all processing steps and determines current image for axes
        function version_control(app,image,title)
            if isempty(app.version{1,1})
                index = 1;
            else
                index = (length(app.version(:,1))) +1;
            end 
            app.version{index,1} = image;
            app.version{index,2} = size(image,3);
            app.version{index,3} = title;
            assignin("base",'version', app.version)
        end

        %% shows current image in axes
        % move is 1 if user wants to show next image and -1 if previous
        function show_image(app,move) 
            if nargin == 1
                index = size(app.version,1);
                app.image_display_counter = size(app.version,1);
                %set temp image to selected dimension               
            else
                if (app.image_display_counter + move) <= size(app.version,1) && (app.image_display_counter + move) >= 1
                    app.image_display_counter = app.image_display_counter + move;
                    index = app.image_display_counter;
                else
                    index = app.image_display_counter;
                end
            end             
            current_image = app.version{index,1};
            image_dims = app.version{index,2};
            current_title = app.version{index,3};
            temp_image = current_image;
            if image_dims > 1
                if app.RedButton.Value
                    temp_image(:,:,1) = current_image(:,:,1);
                    temp_image(:,:,2:3) = 0;
                elseif app.BlueButton.Value
                    temp_image(:,:,3) = current_image(:,:,3);
                    temp_image(:,:,1:2) = 0;
                elseif app.GreenButton.Value
                    temp_image(:,:,2) = current_image(:,:,2);
                    temp_image(:,:,1:2:3) = 0;
                end
            end
            % plot image
            imshow(temp_image,"Parent",app.UIAxes);
            title(app.UIAxes, current_title);
            axis(app.UIAxes,'off');
        end
        
        function  n_intensity = intensity_detection(app, intensity_RGB)

            Intensity_counter = 0 : 255; % vector for the intensity values from 1 to 255
            n_intensity  = nan(size(Intensity_counter));

            for ctr = Intensity_counter
                if ctr == 0
                    n_intensity(ctr+1) = 0;
                else
                    n_intensity(ctr+1) = length(find(intensity_RGB == ctr)); % search for eacht intensity value in the matrix and see how oten it is present

                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialise variables
            app.file = struct();
            app.AllButton.Value = 1;
          
            % go to directory of app
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            app.working_directory = filepath;
            cd(filepath);            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cd(app.working_directory);
            % reset image versions and counter for displaying images
            app.version = cell(1,3);
            app.image_display_counter = 0;

            if app.UselastpathCheckBox.Value == false
                seleceted_path = uigetdir;
                save("App_Settings","seleceted_path");              
            else
                if exist(fullfile(app.working_directory,"App_Settings.mat"),"file")
                    app.app_settings = load("App_Settings.mat","seleceted_path");
                    seleceted_path = cell2mat(struct2cell(app.app_settings));
                else
                    seleceted_path = uigetdir;
                    save("App_Settings","seleceted_path"); 
                end
            end
            cd(seleceted_path);
            filename = uigetfile('.jpg');
            % get app back in foreground
            drawnow;
            figure(app.UIFigure)
            % safe image in struct and version control 
            app.file.raw = imread(filename,'jpg');
            app.FileEditField.Value = filename;
            % transform into double
            app.file.double = cast(app.file.raw,'double') / 255;
            version_control(app,app.file.double,'Raw');
            % check wether backgroundcolor is white or black
            boarderpoint = app.file.double(1,1) + app.file.double(1,end ) + app.file.double(end,1) + app.file.double(end,end);
            if  boarderpoint >=2
                app.file.backgroundcolor = 1; % white background
            else
                app.file.backgroundcolor = 0; % black background
            end
            show_image(app)
        end

        % Selection changed function: RGBDimensionsButtonGroup
        function RGBDimensionsButtonGroupSelectionChanged(app, event)
        show_image(app);
        end

        % Button pushed function: Detect_foot_pixel_Button
        function Detect_foot_pixel_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            app.file.cut_out_logical = app.background_removal(app.file.double, app.file.backgroundcolor, app.Threshold_Slider.Value);
            app.file.cut_out = app.file.cut_out_logical .* app.file.double;
            version_control(app,app.file.cut_out,'Background removed');
            show_image(app);
            hourglass_cursor(app,0);
        end

        % Button pushed function: Refine_detection_Button
        function Refine_detection_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            image_logical = app.file.cut_out_logical;
            for i = 1:app.Iterations_EditField.Value
                image_logical = Neighborhood_refinement(app, image_logical);
            end
            app.file.cut_out_uint8 = cast(image_logical, 'uint8');
            app.file.image_DW = app.file.raw .* app.file.cut_out_uint8;
            version_control(app,app.file.image_DW,'Neighbour refinement');
            show_image(app);
            hourglass_cursor(app,0);
            % --> subplot
        end

        % Value changed function: ExpertmodeButton
        function ExpertmodeButtonValueChanged(app, event)
            if app.ExpertmodeButton.Value
                app.Threshold_Slider.Visible = "on";
                app.ThresholdSliderLabel.Visible = "on";
                app.Detection_window_EditField.Visible = "on";
                app.DetectionWindowEditFieldLabel.Visible = "on";
                app.Neighbour_threshold_Slider.Visible = "on";
                app.NeighbourthresholdLabel.Visible = "on";
                app.Iterations_EditField.Visible = 'on';
                app.alliterationsLabel.Visible = "on";
            else
                app.Threshold_Slider.Visible = "off";
                app.ThresholdSliderLabel.Visible = "off";
                app.Detection_window_EditField.Visible = "off";
                app.DetectionWindowEditFieldLabel.Visible = "off";
                app.Neighbour_threshold_Slider.Visible = "off";
                app.NeighbourthresholdLabel.Visible = "off";
                app.Iterations_EditField.Visible = 'off';
                app.alliterationsLabel.Visible = "off";
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            show_image(app,1)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            show_image(app,-1)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if app.RedButton_2.Value
               intensity  = app.file.image_DW(:,:,1);
               n_Intensity = intensity_detection(app,intensity);
            elseif app.GreenButton_2.Value
                intensity  = app.file.image_DW(:,:,2);
                n_Intensity = intensity_detection(app,intensity);
            elseif app.BlueButton_2.Value
                intensity  = app.file.image_DW(:,:,3);
                n_Intensity = intensity_detection(app,intensity);
            end 
          
           % 
           % aufsummieren der werte des intensity vector / vec-1 verkürzt dann
           Intensity_sum = nan(1,( size(n_Intensity,2)-1 ) )
           for i = 1:size(n_Intensity,2)-1
               if i == 1
                   Intensity_sum(i) = sum(n_Intensity(i) + n_Intensity(i+1));
               else
                   Intensity_sum(i) = sum(Intensity_sum(i-1)+n_Intensity(i+1))
               end
           end
           % modus
           modus = Intensity_sum(end)/2;
           mod_ind = find(Intensity_sum>=modus,1)% ind
           % max
           [max1,max1_ind] = max(n_Intensity(1:mod_ind));
           direction_max2 = n_Intensity(mod_ind:end); %direction to search for second peak right side of modus 
           [max2,max2_ind] = max(direction_max2);
           max2_ind = find(n_Intensity == max2);
           % min
           [minima,min_ind] = min(n_Intensity(max1_ind:max2_ind));
           min_ind= find(n_Intensity == minima);
           % plot
           plot(app.UIAxes2,n_Intensity,'k');
           hold on
           xline(app.UIAxes2,mod_ind,'y');
           xline(app.UIAxes2,max1_ind,'r');
           xline(app.UIAxes2,max2_ind,'r');
           xline(app.UIAxes2,min_ind,'b');
           legend(app.UIAxes2,'Intensity', 'Modus', 'Maximum1', 'Maximum2', 'Minimum');
      
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {582, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RGBDimensionsButtonGroup = uibuttongroup(app.LeftPanel);
            app.RGBDimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RGBDimensionsButtonGroupSelectionChanged, true);
            app.RGBDimensionsButtonGroup.Title = 'RGB Dimensions: ';
            app.RedButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.RedButton.Text = 'Red ';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.GreenButton.Text = 'Green ';
            app.BlueButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.AllButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.AllButton.Text = 'All';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = {'File: '; ''};
            app.FileEditField = uieditfield(app.LeftPanel, 'text');
            app.UselastpathCheckBox = uicheckbox(app.LeftPanel);
            app.UselastpathCheckBox.Text = 'Use last path ';
            app.UselastpathCheckBox.Value = true;
            app.ExpertmodeButton = uibutton(app.LeftPanel, 'state');
            app.ExpertmodeButton.ValueChangedFcn = createCallbackFcn(app, @ExpertmodeButtonValueChanged, true);
            app.ExpertmodeButton.Text = 'Expert mode';
            app.ExpertmodeButton.Value = true;
            app.PreviousButton = uibutton(app.LeftPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing ';
            app.BackgroundDetectionPanel = uipanel(app.DataProcessingTab);
            app.BackgroundDetectionPanel.Title = 'Background Detection';
            app.ThresholdSliderLabel = uilabel(app.BackgroundDetectionPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.Threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Threshold_Slider.Value = 95;
            app.Detect_foot_pixel_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Detect_foot_pixel_Button.ButtonPushedFcn = createCallbackFcn(app, @Detect_foot_pixel_ButtonPushed, true);
            app.Detect_foot_pixel_Button.Text = 'Detect foot pixel';
            app.Refine_detection_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Refine_detection_Button.ButtonPushedFcn = createCallbackFcn(app, @Refine_detection_ButtonPushed, true);
            app.Refine_detection_Button.Text = 'Refine detection';
            app.NeighbourthresholdLabel = uilabel(app.BackgroundDetectionPanel);
            app.NeighbourthresholdLabel.Text = {'Neighbour '; 'threshold'};
            app.Neighbour_threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Neighbour_threshold_Slider.Value = 75;
            app.alliterationsLabel = uilabel(app.BackgroundDetectionPanel);
            app.alliterationsLabel.Text = 'Iterations';
            app.Iterations_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Iterations_EditField.Limits = [1 20];
            app.Iterations_EditField.Value = 4;
            app.DetectionWindowEditFieldLabel = uilabel(app.BackgroundDetectionPanel);
            app.DetectionWindowEditFieldLabel.Text = 'Detection Window';
            app.Detection_window_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Detection_window_EditField.Limits = [3 7];
            app.Detection_window_EditField.Value = 3;
            app.ContactDetectionPanel = uipanel(app.DataProcessingTab);
            app.ContactDetectionPanel.Title = 'Contact Detection';
            app.UIAxes2 = uiaxes(app.ContactDetectionPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RGBDimensionsButtonGroup_2 = uibuttongroup(app.ContactDetectionPanel);
            app.RGBDimensionsButtonGroup_2.Title = 'RGB Dimensions: ';
            app.RedButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.RedButton_2.Text = 'Red ';
            app.RedButton_2.Value = true;
            app.GreenButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.GreenButton_2.Text = 'Green ';
            app.BlueButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.BlueButton_2.Text = 'Blue';
            app.ProcessButton = uibutton(app.ContactDetectionPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = {'Process'; ''};
            app.LandmarkIdentificationTab = uitab(app.TabGroup);
            app.LandmarkIdentificationTab.Title = 'Landmark Identification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Feet_Classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end